classdef Generador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumerodeVariablesSpinner      matlab.ui.control.Spinner
        NmerodeVariablesSpinnerLabel  matlab.ui.control.Label
        NumerodeFilasSpinner          matlab.ui.control.Spinner
        NmerodeFilasSpinnerLabel      matlab.ui.control.Label
        GenerarTablaButton            matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarTablaButton
        function GenerarTablaButtonPushed(app, event)
            try
                % Obtener valores (ya están validados por los spinners)
                filas = app.NumerodeFilasSpinner.Value;
                columnas = app.NumerodeVariablesSpinner.Value;
                
                % Opcional: Solo validar que filas > columnas (para regresión válida)
                if filas <= columnas
                    uialert(app.UIFigure, 'Las observaciones deben ser mayores que las variables', 'Advertencia');
                    return;
                end
                
                % Guardar parámetros y abrir calculadora
                global FILAS_CONFIG COLUMNAS_CONFIG;
                FILAS_CONFIG = filas;
                COLUMNAS_CONFIG = columnas;
                
                run('TablaCalculadora.mlapp');
                delete(app);
                
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Error');
            end
        end

        % Callback function: not associated with a component
        function VerTablasButtonPushed(app, event)
            verDatos();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerarTablaButton = uibutton(app.UIFigure, 'push');
            app.GenerarTablaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarTablaButtonPushed, true);
            app.GenerarTablaButton.Text = 'Generar Tabla';
            app.NmerodeFilasSpinnerLabel = uilabel(app.UIFigure);
            app.NmerodeFilasSpinnerLabel.Text = 'Número de Filas:';
            app.NumerodeFilasSpinner = uispinner(app.UIFigure);
            app.NumerodeFilasSpinner.Limits = [3 20];
            app.NumerodeFilasSpinner.Value = 6;
            app.NmerodeVariablesSpinnerLabel = uilabel(app.UIFigure);
            app.NmerodeVariablesSpinnerLabel.Text = 'Número de Variables:';
            app.NumerodeVariablesSpinner = uispinner(app.UIFigure);
            app.NumerodeVariablesSpinner.Limits = [1 10];
            app.NumerodeVariablesSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Generador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end