classdef TablaCalculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GuardarTablaButton             matlab.ui.control.Button
        GradosdeLibertadSpinner        matlab.ui.control.Spinner
        GradosdeLibertadLabel          matlab.ui.control.Label
        ValoresEnXLabel                matlab.ui.control.Label
        ResultadodelIntervaloTextArea  matlab.ui.control.TextArea
        ResultadodelIntervaloTextAreaLabel  matlab.ui.control.Label
        PredecirButton                 matlab.ui.control.Button
        AgregarEjemploButton           matlab.ui.control.Button
        ResultadoTextArea              matlab.ui.control.TextArea
        ResultadoTextAreaLabel         matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        Table                          matlab.ui.control.Table
    end

    
    properties (Access = private)
        ResultadosRegresion = []  % Para guardar los resultados
        CamposPrediccion = {}     % Para los EditFields de predicción
        LabelsPrediccion = {}     % Para los Labels de predicción
    end

    methods (Access = private)
        
        function configurarTabla(app, filas, columnas)
            % Obtener nombres de variables
            global NOMBRES_VARS_CONFIG;
            
            % Crear nombres de columnas dinámicamente
            nombres_columnas = cell(1, columnas + 1);
            nombres_columnas{1} = 'Y (Dependiente)';
            
            if ~isempty(NOMBRES_VARS_CONFIG)
                for i = 1:columnas
                    nombres_columnas{i+1} = NOMBRES_VARS_CONFIG{i};
                end
                % Limpiar variable global
                clear global NOMBRES_VARS_CONFIG;
            else
                for i = 1:columnas
                    nombres_columnas{i+1} = sprintf('X%d', i);
                end
            end
            
            % Configurar la tabla
            app.Table.ColumnName = nombres_columnas;
            app.Table.ColumnEditable = true(1, columnas + 1);
            
            % Crear matriz vacía
            datos_vacios = zeros(filas, columnas + 1);
            app.Table.Data = datos_vacios;
            
            fprintf('Tabla configurada con nombres personalizados\n');
        end

        function cargarDatosEjemplo(app, filas, columnas)
            % Crear matriz con ceros para que el usuario ingrese sus datos
            datos_vacios = zeros(filas, columnas + 1);
            app.Table.Data = datos_vacios;
            
            fprintf('Tabla inicializada con ceros para ingreso manual de datos\n');
        end

        function crearCamposPrediccion(app, numVariables)
        % Limpiar campos anteriores
        for i = 1:length(app.CamposPrediccion)
            if isvalid(app.CamposPrediccion{i})
                delete(app.CamposPrediccion{i});
            end
        end
        for i = 1:length(app.LabelsPrediccion)
            if isvalid(app.LabelsPrediccion{i})
                delete(app.LabelsPrediccion{i});
            end
        end
                
        % Crear campos de entrada X1, X2, X3...
        app.CamposPrediccion = cell(numVariables, 1);
        app.LabelsPrediccion = cell(numVariables, 1);
    
        % Posiciones para X1, X2, X3... 
        baseX = 430;
        baseY = 320;
    
        for i = 1:numVariables
            % Labels
            app.LabelsPrediccion{i} = uilabel(app.UIFigure);
            app.LabelsPrediccion{i}.Position = [baseX, baseY - (i-1)*30, 30, 22];
            app.LabelsPrediccion{i}.Text = sprintf('X%d =', i);
            app.LabelsPrediccion{i}.FontWeight = 'bold';
            
            % Campos numéricos
            app.CamposPrediccion{i} = uieditfield(app.UIFigure, 'numeric');
            app.CamposPrediccion{i}.Position = [baseX + 35, baseY - (i-1)*30, 80, 22];
            app.CamposPrediccion{i}.Value = 0;
        end
      
        % Texto "Ingrese valores" (posición actual)
        app.ValoresEnXLabel.Position = [410, 200, 200, 165];
        app.ValoresEnXLabel.Text = 'Ingrese valores y presione Predecir';
        app.ValoresEnXLabel.FontSize = 11;
        app.ValoresEnXLabel.FontWeight = 'bold';
        app.ValoresEnXLabel.FontColor = [0.8500 0.3250 0.0980];
        app.ValoresEnXLabel.VerticalAlignment = 'top';
        app.ValoresEnXLabel.HorizontalAlignment = 'center';
        app.ValoresEnXLabel.BackgroundColor = [0.96 0.96 0.96];
        app.ValoresEnXLabel.WordWrap = 'on';
    
        % MOSTRAR EL SPINNER DE GRADOS DE LIBERTAD
        app.GradosdeLibertadSpinner.Visible = 'on';
        app.GradosdeLibertadLabel.Visible = 'on';
        app.GuardarTablaButton.Visible = 'on';
        app.ResultadodelIntervaloTextArea.Visible ="on";
        app.ResultadodelIntervaloTextAreaLabel.Visible = 'on';

    
        % Callback del botón Predecir
        if isvalid(app.PredecirButton)
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed, true);
        end
        
        fprintf('Campos de predicción y spinner de confianza creados\n');
        end

        function [Y_pred, intervalo_inf, intervalo_sup] = calcularPrediccionConIntervalo(app, resultados, X_valores, nivel_confianza)
            % Calcular predicción puntual
            beta = resultados.beta;
            Y_pred = beta(1);
            for i = 1:length(X_valores)
                Y_pred = Y_pred + beta(i+1) * X_valores(i);
            end
            
            % Parámetros para intervalo
            MSE = resultados.MSE;
            n = resultados.n;
            k = resultados.k;
            SE_pred = sqrt(MSE * (1 + 1/n));
            gl = n - k - 1;
            
            % Valor crítico según nivel de confianza (SINTAXIS MATLAB CORRECTA)
            if nivel_confianza >= 99
                if gl > 30
                    t_critico = 2.576;
                else
                    t_critico = 2.6;
                end
            elseif nivel_confianza >= 95
                if gl > 30
                    t_critico = 1.96;
                else
                    t_critico = 2.0;
                end
            elseif nivel_confianza >= 90
                if gl > 30
                    t_critico = 1.645;
                else
                    t_critico = 1.7;
                end
            else
                % Por defecto 95%
                if gl > 30
                    t_critico = 1.96;
                else
                    t_critico = 2.0;
                end
            end
            
            % Calcular intervalos
            margen_error = t_critico * SE_pred;
            intervalo_inf = Y_pred - margen_error;
            intervalo_sup = Y_pred + margen_error;
        end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtener parámetros de la configuración
            global FILAS_CONFIG COLUMNAS_CONFIG;
            
            if ~isempty(FILAS_CONFIG) && ~isempty(COLUMNAS_CONFIG)
                filas = FILAS_CONFIG;
                columnas = COLUMNAS_CONFIG;
                
                % Limpiar variables globales
                clear global FILAS_CONFIG COLUMNAS_CONFIG;
            else
                % Valores por defecto si no hay parámetros
                filas = 6;
                columnas = 3;
            end
            
            % Configurar la tabla dinámicamente
            configurarTabla(app, filas, columnas);
            
            % Cargar datos de ejemplo
            cargarDatosEjemplo(app, filas, columnas);

            % Ocultar los elementos
            app.GradosdeLibertadSpinner.Visible = 'off';
            app.GradosdeLibertadLabel.Visible = 'off';
            app.GuardarTablaButton.Visible = 'off';
            app.ResultadodelIntervaloTextArea.Visible ="off";
            app.ResultadodelIntervaloTextAreaLabel.Visible = 'off';


        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            try
                % Obtener y validar datos
                datos = app.Table.Data;
                
                if isempty(datos) || size(datos, 1) < 3
                    uialert(app.UIFigure, 'Se necesitan al menos 3 observaciones.', 'Error');
                    return;
                end
                
                % Convertir a matriz numérica
                if iscell(datos)
                    datos_num = zeros(size(datos));
                    for i = 1:size(datos, 1)
                        for j = 1:size(datos, 2)
                            if isnumeric(datos{i,j})
                                datos_num(i,j) = datos{i,j};
                            else
                                datos_num(i,j) = str2double(datos{i,j});
                            end
                        end
                    end
                else
                    datos_num = datos;
                end
                
                if any(isnan(datos_num(:)))
                    uialert(app.UIFigure, 'Los datos contienen valores no válidos.', 'Error');
                    return;
                end
                
                % Definir variables
                Y = datos_num(:, 1);
                X = datos_num(:, 2:end);
                
                fprintf('📊 Calculando: %d obs, %d vars\n', length(Y), size(X, 2));
                
                % Verificar multicolinealidad (simple)
                X_test = [ones(size(X, 1), 1), X];
                if rank(X_test) < size(X_test, 2)
                    resp = questdlg('Multicolinealidad detectada. ¿Usar solo X1?', ...
                                   'Advertencia', 'Sí', 'Cancelar', 'Sí');
                    if ~strcmp(resp, 'Sí'), return; end
                    X = X(:, 1);
                end
                
                % Calcular regresión
                resultados = calcularRegresionMultiple(Y, X);
                
                % *** CRÍTICO: Guardar resultados para predicciones ***
                app.ResultadosRegresion = resultados;
                
                % *** GUARDAR DATOS USANDO UserData (SIN propiedades dinámicas) ***
                datosTemp = struct();
                datosTemp.Y = Y;
                datosTemp.X = X;
                datosTemp.resultados = resultados;
                datosTemp.timestamp = now;
                
                % Guardar en UserData de la tabla (o cualquier componente)
                app.Table.UserData = datosTemp;
                
                fprintf('💾 Datos guardados temporalmente para el botón Guardar\n');
                
                % Crear texto de resultados (compacto)
                app.ResultadoTextArea.Value = sprintf(['Intersección: %.4f\n\n' ...
                                                        'Fórmula: %s\n\n' ...
                                                        'Probabilidad: %.1f%%'], ...
                                                        resultados.beta(1), ...
                                                        resultados.ecuacion, ...
                                                        resultados.R2 * 100);
                
                
                % Crear campos predicción si existe la función
                try
                    crearCamposPrediccion(app, size(X, 2));
                catch
                    % Si no existe la función, continuar sin error
                end

                % Mensaje de éxito
                uialert(app.UIFigure, ...
                    sprintf('¡Cálculo exitoso!\nR² = %.4f (%.1f%%)', resultados.R2, resultados.R2*100), ...
                    'Completado', 'Icon', 'success');
                
                fprintf('✅ Regresión calculada exitosamente\n');
                
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
                fprintf('❌ Error: %s\n', ME.message);
            end
        end

        % Button pushed function: AgregarEjemploButton
        function AgregarEjemploButtonPushed(app, event)
            try
                % Cambiar texto del botón
                app.AgregarEjemploButton.Text = '📂 Cargando...';
                app.AgregarEjemploButton.Enable = 'off';
                drawnow;
                
                % Conectar a base de datos
                javaaddpath('C:\Program Files\MATLAB\R2024b\postgresql-42.7.5.jar');
                conn = database('bd_RegresionLineal', 'postgres', 'francesfranco2004', ...
                    'org.postgresql.Driver', ...
                    'jdbc:postgresql://localhost:5432/bd_RegresionLineal');
                
                if ~isempty(conn.Message)
                    uialert(app.UIFigure, 'Error de conexión a la base de datos', 'Error BD');
                    app.AgregarEjemploButton.Text = '📂 Cargar Dataset';
                    app.AgregarEjemploButton.Enable = 'on';
                    return;
                end
                
                % Obtener lista de datasets
                datasets = fetch(conn, 'SELECT id, nombre, descripcion, num_observaciones, num_variables_indep FROM datasets ORDER BY id DESC');
                
                if isempty(datasets)
                    uialert(app.UIFigure, 'No hay datasets en la base de datos', 'Sin Datos');
                    close(conn);
                    app.AgregarEjemploButton.Text = '📂 Cargar Dataset';
                    app.AgregarEjemploButton.Enable = 'on';
                    return;
                end
                
                % Crear lista de opciones
                opciones = cell(height(datasets), 1);
                for i = 1:height(datasets)
                    opciones{i} = sprintf('%s (%dx%d) - %s', ...
                        datasets.nombre{i}, ...
                        datasets.num_observaciones(i), ...
                        datasets.num_variables_indep(i), ...
                        datasets.descripcion{i});
                end
                
                % Mostrar diálogo de selección
                [indx, tf] = listdlg(...
                    'PromptString', 'Seleccione el dataset que desea cargar:', ...
                    'SelectionMode', 'single', ...
                    'ListString', opciones, ...
                    'ListSize', [500, 250], ...
                    'Name', 'Cargar Dataset');
                
                if tf
                    % Cargar datos del dataset seleccionado
                    dataset_id = datasets.id(indx);
                    
                    % Obtener observaciones
                    query_obs = sprintf('SELECT variable_dependiente, variables_independientes FROM observaciones WHERE dataset_id = %d ORDER BY fila', dataset_id);
                    observaciones = fetch(conn, query_obs);
                    
                    % Convertir datos (CORRECCIÓN PARA MANEJAR PgArray)
                    Y = observaciones.variable_dependiente;
                    X_cell = observaciones.variables_independientes;
                    
                    % Determinar número de variables
                    if iscell(X_cell) && ~isempty(X_cell)
                        % Obtener el primer array para determinar el tamaño
                        primer_array = X_cell{1};
                        if isa(primer_array, 'org.postgresql.jdbc.PgArray')
                            % Convertir PgArray a array de MATLAB
                            primer_datos = primer_array.getArray();
                            num_vars = length(primer_datos);
                        else
                            num_vars = length(primer_array);
                        end
                    else
                        num_vars = datasets.num_variables_indep(indx);
                    end
                    
                    % Inicializar matriz X
                    X = zeros(length(Y), num_vars);
                    
                    % Convertir cada array de PostgreSQL
                    for i = 1:length(Y)
                        if isa(X_cell{i}, 'org.postgresql.jdbc.PgArray')
                            % Es un PgArray de PostgreSQL
                            array_datos = X_cell{i}.getArray();
                            % Convertir a números
                            for j = 1:length(array_datos)
                                X(i, j) = double(array_datos(j));
                            end
                        elseif iscell(X_cell{i})
                            % Es un cell array
                            for j = 1:length(X_cell{i})
                                X(i, j) = double(X_cell{i}{j});
                            end
                        else
                            % Es un array numérico normal
                            X(i, :) = double(X_cell{i});
                        end
                    end
                    
                    % Cargar en la tabla
                    app.Table.Data = [Y, X];
                    
                    % Actualizar nombres de columnas
                    nombres_columnas = cell(1, num_vars + 1);
                    nombres_columnas{1} = 'Y (Dependiente)';
                    for i = 1:num_vars
                        nombres_columnas{i+1} = sprintf('X%d', i);
                    end
                    app.Table.ColumnName = nombres_columnas;
                    
                    uialert(app.UIFigure, sprintf('Dataset "%s" cargado exitosamente', datasets.nombre{indx}), 'Éxito', 'Icon', 'success');
                    
                    fprintf('✅ Dataset cargado: %d filas x %d variables\n', length(Y), num_vars);
                end
                
                close(conn);
                
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Error');
                fprintf('Error detallado: %s\n', ME.message);
                if ~isempty(ME.stack)
                    fprintf('En línea: %d\n', ME.stack(1).line);
                end
            end
            
            % Restaurar botón
            app.AgregarEjemploButton.Text = '📂 Cargar Dataset';
            app.AgregarEjemploButton.Enable = 'on';
        end

        % Button pushed function: PredecirButton
        function PredecirButtonPushed(app, event)
        try
            if isempty(app.ResultadosRegresion)
                app.ResultadodelIntervaloTextArea.Value = {'❌ Calcule primero la regresión'};
                return;
            end
            
            % Obtener valores de X
            numVars = length(app.CamposPrediccion);
            X_valores = zeros(1, numVars);
            for i = 1:numVars
                X_valores(i) = app.CamposPrediccion{i}.Value;
            end
            
            % Obtener nivel de confianza del spinner
            nivel_confianza = app.GradosdeLibertadSpinner.Value;
            
            % Calcular predicción con nivel personalizado
            [Y_predicho, intervalo_inf, intervalo_sup] = calcularPrediccionConIntervalo(app, app.ResultadosRegresion, X_valores, nivel_confianza);
            
            % Mostrar resultado
            app.ResultadodelIntervaloTextArea.Value = {
                '🔮 PREDICCIÓN CALCULADA:';
                '';
                sprintf('🎯 Y = %.4f', Y_predicho);
                '';
                sprintf('📊 Intervalo de Confianza %d%%:', nivel_confianza);
                sprintf('   [%.4f, %.4f]', intervalo_inf, intervalo_sup);
                '';
                sprintf('💡 Con %d%% de confianza, el valor real', nivel_confianza);
                sprintf('   de Y estará entre %.2f y %.2f', intervalo_inf, intervalo_sup)
            };
            
            fprintf('Predicción con %d%% de confianza: Y = %.4f [%.4f, %.4f]\n', nivel_confianza, Y_predicho, intervalo_inf, intervalo_sup);
            
        catch ME
            app.ResultadodelIntervaloTextArea.Value = {sprintf('❌ Error: %s', ME.message)};
        end
        end

        % Button pushed function: GuardarTablaButton
        function GuardarTablaButtonPushed(app, event)
            try
                % *** OBTENER DATOS DE UserData (en lugar de propiedades dinámicas) ***
                if isempty(app.Table.UserData)
                    uialert(app.UIFigure, 'No hay datos para guardar. Calcule primero.', 'Error');
                    return;
                end
                
                datosTemp = app.Table.UserData;
                
                % Verificar que la estructura tenga los campos necesarios
                if ~isfield(datosTemp, 'Y') || ~isfield(datosTemp, 'X') || ~isfield(datosTemp, 'resultados')
                    uialert(app.UIFigure, 'Los datos no están disponibles. Calcule nuevamente.', 'Error');
                    return;
                end
                
                % Obtener datos ya calculados
                Y = datosTemp.Y;
                X = datosTemp.X;
                resultados = datosTemp.resultados;
                
                fprintf('💾 Guardando dataset: %d obs, %d vars, R²=%.4f\n', ...
                        length(Y), size(X, 2), resultados.R2);
                
                % Pedir nombre y descripción (simple)
                prompt = {'Nombre del dataset:', 'Descripción:'};
                titulo = 'Guardar en Base de Datos';
                
                defecto = {
                    sprintf('Dataset_%s', datestr(now, 'dd_mm_HH_MM'));
                    sprintf('Regresión R²=%.3f (%d obs)', resultados.R2, length(Y))
                };
                
                respuesta = inputdlg(prompt, titulo, [1 50; 1 50], defecto);
                
                if isempty(respuesta)
                    fprintf('⚠ Guardado cancelado\n');
                    return;
                end
                
                % Procesar entrada
                nombre = strtrim(respuesta{1});
                descripcion = strtrim(respuesta{2});
                
                if isempty(nombre), nombre = defecto{1}; end
                if isempty(descripcion), descripcion = defecto{2}; end
                
                % Limpiar caracteres especiales
                nombre = strrep(nombre, '''', '');
                descripcion = strrep(descripcion, '''', '');
                
                % Guardar en BD
                fprintf('💾 Guardando "%s" en PostgreSQL...\n', nombre);
                exito = guardarEnBD(Y, X, nombre, descripcion);
                
                if exito
                    uialert(app.UIFigure, ...
                        sprintf('¡Guardado exitoso!\n\nNombre: %s\nR²: %.4f', nombre, resultados.R2), ...
                        'Éxito', 'Icon', 'success');
                    
                    fprintf('✅ Dataset guardado exitosamente\n');
                    
                    % Limpiar datos temporales después de guardar
                    app.Table.UserData = [];
                    
                else
                    uialert(app.UIFigure, 'Error al guardar en la base de datos.', 'Error');
                    fprintf('❌ Error en guardado\n');
                end
                
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
                fprintf('❌ Error: %s\n', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular Regresión';
            app.ResultadoTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadoTextAreaLabel.Text = 'Resultado:';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            app.AgregarEjemploButton = uibutton(app.UIFigure, 'push');
            app.AgregarEjemploButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarEjemploButtonPushed, true);
            app.AgregarEjemploButton.Text = 'Agregar Ejemplo';
            app.PredecirButton = uibutton(app.UIFigure, 'push');
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed, true);
            app.PredecirButton.Text = 'Predecir';
            app.ResultadodelIntervaloTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadodelIntervaloTextAreaLabel.Text = {'Resultado'; 'del Intervalo:'};
            app.ResultadodelIntervaloTextArea = uitextarea(app.UIFigure);
            app.ValoresEnXLabel = uilabel(app.UIFigure);
            app.ValoresEnXLabel.Text = '';
            app.GradosdeLibertadLabel = uilabel(app.UIFigure);
            app.GradosdeLibertadLabel.Text = {'Grados de'; 'Libertad'};
            app.GradosdeLibertadSpinner = uispinner(app.UIFigure);
            app.GradosdeLibertadSpinner.Limits = [90 99];
            app.GradosdeLibertadSpinner.Value = 95;
            app.GuardarTablaButton = uibutton(app.UIFigure, 'push');
            app.GuardarTablaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarTablaButtonPushed, true);
            app.GuardarTablaButton.Text = 'Guardar Tabla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TablaCalculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end